classdef mEMbrain_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mEMbrainUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NeurontoMembraneTab             matlab.ui.container.Tab
        FillExtracellularSpaceECSSwitch  matlab.ui.control.Switch
        FillExtracellularSpaceECSSwitchLabel  matlab.ui.control.Label
        NtoMResetButton                 matlab.ui.control.Button
        NtoMConvertButton               matlab.ui.control.Button
        MembraneGroundTruthPreviewLabel  matlab.ui.control.Label
        borderThicknessSpinner          matlab.ui.control.Spinner
        SetmembranethicknesstoLabel     matlab.ui.control.Label
        MembranePropertiesLabel         matlab.ui.control.Label
        SelectMembraneOutputDirectoryButton  matlab.ui.control.Button
        MembraneGroundTruthLabel        matlab.ui.control.Label
        SelectNeuronInputDirectoryButton  matlab.ui.control.Button
        NeuronGroundTruthLabel          matlab.ui.control.Label
        NtoMDescriptionLabel            matlab.ui.control.Label
        ConvertNeuronGroundTruthDatatoMembraneGroundTruthDataLabel  matlab.ui.control.Label
        BorderPreviewAxes               matlab.ui.control.UIAxes
        MembranetoSkeletonTab           matlab.ui.container.Tab
        GaussianFilterSizeButtonGroup   matlab.ui.container.ButtonGroup
        Label                           matlab.ui.control.Label
        sizeFiveButton                  matlab.ui.control.ToggleButton
        sizeThreeButton                 matlab.ui.control.ToggleButton
        sizeOneButton                   matlab.ui.control.ToggleButton
        Doesthegroundtruthcontainanunknown0labelButtonGroup  matlab.ui.container.ButtonGroup
        noButton                        matlab.ui.control.ToggleButton
        yesButton                       matlab.ui.control.ToggleButton
        MembranePropertiesLabel_2       matlab.ui.control.Label
        MtoSResetButton                 matlab.ui.control.Button
        MtoSConvertButton               matlab.ui.control.Button
        SkeletonGroundTruthPreviewLabel  matlab.ui.control.Label
        SelectSkeletonOutputDirectoryButton  matlab.ui.control.Button
        SkeletonGroundTruthLabel        matlab.ui.control.Label
        SelectMembraneInputDirectoryButton  matlab.ui.control.Button
        MembraneGroundTruthLabel_2      matlab.ui.control.Label
        MtoSDescriptionLabel            matlab.ui.control.Label
        ConvertMembraneGroundTruthDatatoSkeletonGroundTruthDataLabel  matlab.ui.control.Label
        SkeletonPreviewAxes             matlab.ui.control.UIAxes
        PatchGenerationTab              matlab.ui.container.Tab
        advancedSettingsButton          matlab.ui.control.Button
        patchesLabel                    matlab.ui.control.Label
        PatchDensityEditField           matlab.ui.control.NumericEditField
        NumberofpatchespermegapixelEditFieldLabel  matlab.ui.control.Label
        PatchesResetButton              matlab.ui.control.Button
        GeneratePatchesButton           matlab.ui.control.Button
        pixelsbypixelsLabel             matlab.ui.control.Label
        PatchSizeEditField              matlab.ui.control.NumericEditField
        PatchSizeEditFieldLabel         matlab.ui.control.Label
        selectLabelPatchDirectory       matlab.ui.control.Button
        selectLabelDirectory            matlab.ui.control.Button
        selectInputPatchDirectory       matlab.ui.control.Button
        LabeledOutputData               matlab.ui.control.Label
        selectInputDirectory            matlab.ui.control.Button
        InputDataLabel                  matlab.ui.control.Label
        MtoSDescriptionLabel_2          matlab.ui.control.Label
        GeneratePatchesofInputandLabeledGroundTruthData  matlab.ui.control.Label
        TrainaNetworkTab                matlab.ui.container.Tab
        AdvancedSettingsPanel           matlab.ui.container.Panel
        trainingSplitEditField          matlab.ui.control.NumericEditField
        TrainingValidationSplitTrainingDataSlider  matlab.ui.control.Slider
        TrainingValidationSplitTrainingDataLabel  matlab.ui.control.Label
        TrainingInformationDisplayPropertiesLabel  matlab.ui.control.Label
        TrainingPropertiesLabel         matlab.ui.control.Label
        NeuralNetworkPropertiesLabel    matlab.ui.control.Label
        ByhowmuchEditField              matlab.ui.control.NumericEditField
        ByhowmuchEditFieldLabel         matlab.ui.control.Label
        DroplearningrateduringtrainingSwitch  matlab.ui.control.Switch
        DroplearningrateduringtrainingLabel  matlab.ui.control.Label
        PlottrainingprogressSwitch      matlab.ui.control.Switch
        PlottrainingprogressSwitchLabel  matlab.ui.control.Label
        PrinttrainingprogressSwitch     matlab.ui.control.Switch
        PrinttrainingprogressSwitchLabel  matlab.ui.control.Label
        ShuffletrainingdataSwitch       matlab.ui.control.Switch
        ShuffletrainingdataSwitchLabel  matlab.ui.control.Label
        TrainingoptimizerDropDown       matlab.ui.control.DropDown
        TrainingoptimizerDropDownLabel  matlab.ui.control.Label
        UNETnetworkdepthEditField       matlab.ui.control.NumericEditField
        UNETnetworkdepthEditFieldLabel  matlab.ui.control.Label
        InitialLearningRateEditField    matlab.ui.control.NumericEditField
        InitialLearningRateEditFieldLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        TrainNetworkButton              matlab.ui.control.Button
        SavenetworkasDropDown           matlab.ui.control.DropDown
        SavenetworkasDropDownLabel      matlab.ui.control.Label
        selectTrainedNetworkDirectoryButton  matlab.ui.control.Button
        SavetrainedneuralnetworktoLabel  matlab.ui.control.Label
        NetworkNameEditField            matlab.ui.control.EditField
        NetworkNameEditFieldLabel       matlab.ui.control.Label
        numOfClassesSpinner             matlab.ui.control.Spinner
        ofclassesSpinnerLabel           matlab.ui.control.Label
        networkButtonGroup              matlab.ui.container.ButtonGroup
        importNetworkButton             matlab.ui.control.ToggleButton
        unetButton                      matlab.ui.control.ToggleButton
        WhichnetworkwouldyouliketotrainLabel  matlab.ui.control.Label
        numOfEpochsSpinner              matlab.ui.control.Spinner
        ofepochsSpinnerLabel            matlab.ui.control.Label
        selectLabelDataDirectoryButton  matlab.ui.control.Button
        LabelDataDirectory              matlab.ui.control.Label
        selectInputDataDirectoryButton  matlab.ui.control.Button
        InputDataDirectoryLabel         matlab.ui.control.Label
        TrainaNetworkLabel              matlab.ui.control.Label
        EvaluateaNetworkTab             matlab.ui.container.Tab
        currentPreviewPathLabel         matlab.ui.control.Label
        savePredictionsToButton         matlab.ui.control.Button
        DisplayOptionsPanel             matlab.ui.container.Panel
        predictDisplayButtonGroup       matlab.ui.container.ButtonGroup
        showProbabilityButton           matlab.ui.control.ToggleButton
        showPredictionsButton           matlab.ui.control.ToggleButton
        ShowInputDataSwitch             matlab.ui.control.Switch
        ShowInputDataSwitchLabel        matlab.ui.control.Label
        NextPredictButton               matlab.ui.control.Button
        PreviousPredictButton           matlab.ui.control.Button
        ResetPredictButton              matlab.ui.control.Button
        PredictButton                   matlab.ui.control.Button
        selectPredictionInputButton     matlab.ui.control.Button
        SelecttheinputimagesforpredictionLabel  matlab.ui.control.Label
        selectPredictionNetworkButton   matlab.ui.control.Button
        WhichnetworkwouldyouliketousetomakeapredictionLabel  matlab.ui.control.Label
        EvaluateaNetworkLabel           matlab.ui.control.Label
        PredictionPreviewAxes           matlab.ui.control.UIAxes
        PredictfromVASTTab              matlab.ui.container.Tab
        ConvertVSVItoVSSEGPanel         matlab.ui.container.Panel
        ConversionXFullRangeCheckbox    matlab.ui.control.CheckBox
        ConversionXEndEditField         matlab.ui.control.NumericEditField
        toLabel_6                       matlab.ui.control.Label
        ConversionXStartEditField       matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        ConversionYFullRangeCheckbox    matlab.ui.control.CheckBox
        EditField_12                    matlab.ui.control.NumericEditField
        toLabel_5                       matlab.ui.control.Label
        ConversionYStartEditField       matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        ConversionZFullRangeCheckbox    matlab.ui.control.CheckBox
        ConversionZEndEditField         matlab.ui.control.NumericEditField
        toLabel_4                       matlab.ui.control.Label
        ConversionZStartEditField       matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        VSSEGLocationMethodButtonGroup  matlab.ui.container.ButtonGroup
        RandomButton                    matlab.ui.control.ToggleButton
        SourceSkeletonButton_2          matlab.ui.control.ToggleButton
        ConvertButton                   matlab.ui.control.Button
        ConversionThresholdEditField    matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        ConversionTileSizeEditField     matlab.ui.control.NumericEditField
        TileSizeLabel                   matlab.ui.control.Label
        ConversionMIPLevelSpinner       matlab.ui.control.Spinner
        ConversionMIPLevelLabel         matlab.ui.control.Label
        ConversionSourceAnnoLayerDropDown  matlab.ui.control.DropDown
        ConversionAnnoLayerDropDown     matlab.ui.control.DropDown
        ConversionSegLayerDropDown      matlab.ui.control.DropDown
        ConversionImageLayerDropDown    matlab.ui.control.DropDown
        SourceAnnotationLayerLabel      matlab.ui.control.Label
        AnnotationLayerLabel_2          matlab.ui.control.Label
        SegmentationLayerLabel          matlab.ui.control.Label
        ImageLayerLabel                 matlab.ui.control.Label
        GeneratePredictionsfromEMVSVIPanel  matlab.ui.container.Panel
        GenerateVSVICheckBox            matlab.ui.control.CheckBox
        XFullrangeCheckBox              matlab.ui.control.CheckBox
        XEndEditField                   matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        XStartEditField                 matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        YFullrangeCheckBox              matlab.ui.control.CheckBox
        YEndEditField                   matlab.ui.control.NumericEditField
        toLabel_2                       matlab.ui.control.Label
        YStartEditField                 matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        ZFullrangeCheckBox              matlab.ui.control.CheckBox
        ZEndEditField                   matlab.ui.control.NumericEditField
        toLabel_3                       matlab.ui.control.Label
        ZStartEditField                 matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        PredictFromVSVIButton           matlab.ui.control.Button
        SelectPredDirectoryButton       matlab.ui.control.Button
        SelectProbDirectoryButton       matlab.ui.control.Button
        NumPredictionClassesSpinner     matlab.ui.control.Spinner
        ofclassesLabel                  matlab.ui.control.Label
        TrainedNetworkPredictionSelectButton  matlab.ui.control.Button
        MIPLevelSpinner                 matlab.ui.control.Spinner
        MIPLevelLabel_2                 matlab.ui.control.Label
        PredictionRangeButtonGroup      matlab.ui.container.ButtonGroup
        SkeletonButton                  matlab.ui.control.ToggleButton
        PartofVolumeButton              matlab.ui.control.ToggleButton
        WholeVolumeButton               matlab.ui.control.ToggleButton
        ImageLayerDropDown              matlab.ui.control.DropDown
        ImageLayerLabel_2               matlab.ui.control.Label
        PortEditField                   matlab.ui.control.NumericEditField
        PortEditFieldLabel              matlab.ui.control.Label
        ConnectToVASTButton             matlab.ui.control.Button
        GeneratePredictionsfromVASTLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        neuronInputDirectory % Neuron GT input directory (set in SelectNeuronInputDirectoryButtonPushed)
        membraneOutputDirectory % Membrane GT output directory (set in SelectMembraneOutputDirectoryButtonPushed)
        membraneInputDirectory % Membrane GT input directory (set in SelectMembraneInputDirectoryButtonPushed)
        skeletonOutputDirectory % Skeleton GT output directory (set in SelectSkeletonOutputDirectoryButtonPushed)
        borderThickness % Size of erosion/dilation structure element neighborhood (the "thickness" of the borders)
        filterSize % Size of Gau. ssian filter for noise removal and border smoothing to prevent over-representation of irregular borders
        zeroPixelUsed % True if membrane GT includes a "don't know" 0 pixel, false if otherwise
        patchGenInputDirectory % Input data directory for patch generation
        patchGenInputPatchDirectory % Input data patch directory for patch generation
        patchGenOutputDirectory % Output data directory for patch generation
        patchGenOutputPatchDirectory % Output data patch directory for patch generation
        patchGenPatchSize % Patch size for patch generation
        patchGenPatchDensity % Patches per megapixel for patch generation
        patchGenContrastCorrect % Contrast correction for patch generation
        patchGenDownsamplingFactor % Downsampling factor for patch generation
        AdvancedSettingsApp % Advanced settings popup window for patch generation
        deepInputDirectory % Input data directory for deep learning
        deepLabelDirectory % Label data directory for deep learning
        deepImportedNetwork % Imported neural network for deep learning
        deepSaveNetworkDirectory % Trained network directory for deep learning
        predictNetwork % Trained network for prediction
        predictSaveDirectory % Directory to save predictions to
        predictInputImages % Images to predict with trained network
        predictions % Predictions produced by deepLearningPredict
        currentPredictIndex % Index of currently displayed prediction in preview
        vastPredictionsTrainedNetwork % Trained net for VAST VSVI predictions
        vastPredictionsPredictionDirectory % Prediction directory for VAST VSVI predictions
        vastPredictionsProbabilitiesDirectory % Probability directory for VAST VSVI predictions
        vast % VAST Control Class
        vastLayers % Layers open in VAST
        vastProbabilitiesDirectory
        vastPredictionsDirectory
    end
    
    methods (Access = public)
        function updateAdvancedSettings(app, contrastCorrect, downsample)
            app.patchGenContrastCorrect = contrastCorrect;
            app.patchGenDownsamplingFactor = downsample;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set border preview axes initial state
        
            title(app.BorderPreviewAxes, []);
            xlabel(app.BorderPreviewAxes, []);
            ylabel(app.BorderPreviewAxes, []);
            app.BorderPreviewAxes.XAxis.TickLabels = {};
            app.BorderPreviewAxes.YAxis.TickLabels = {};
            
            % Set skeleton preview axes initial state
        
            title(app.SkeletonPreviewAxes, []);
            xlabel(app.SkeletonPreviewAxes, []);
            ylabel(app.SkeletonPreviewAxes, []);
            app.SkeletonPreviewAxes.XAxis.TickLabels = {};
            app.SkeletonPreviewAxes.YAxis.TickLabels = {};
            
            % Set predictin preview axes initial state
            
            title(app.PredictionPreviewAxes, []);
            xlabel(app.PredictionPreviewAxes, []);
            ylabel(app.PredictionPreviewAxes, []);
            app.PredictionPreviewAxes.XAxis.TickLabels = {};
            app.PredictionPreviewAxes.YAxis.TickLabels = {};
            
            vastElementsLeft = get(app.GeneratePredictionsfromEMVSVIPanel, 'Children');
            set(findall(vastElementsLeft, '-property', 'Enable'), 'Enable', 'Off');
            vastElementsRight = get(app.ConvertVSVItoVSSEGPanel, 'Children');
            set(findall(vastElementsRight, '-property', 'Enable'), 'Enable', 'Off');
            
        end

        % Button pushed function: SelectNeuronInputDirectoryButton
        function SelectNeuronInputDirectoryButtonPushed(app, event)
            % When the "Select Input Directory" button is pushed, the user
            % selects a directory for the neuron GT (TODO: expand to
            % reverse problem?) whose images will be processed when the
            % "Convert" button is pressed.
            
            selectInputDirectory = uigetdir(pwd, "Select Input Label Folder");
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.neuronInputDirectory = selectInputDirectory;
                app.SelectNeuronInputDirectoryButton.Text = strcat('Selected Input Directory: ', app.neuronInputDirectory);
            end
            
            % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.neuronInputDirectory) && ~isempty(app.membraneOutputDirectory)
                app.NtoMConvertButton.Enable = 'on';
            end
            
        end

        % Button pushed function: SelectMembraneOutputDirectoryButton
        function SelectMembraneOutputDirectoryButtonPushed(app, event)
            % When the "Select Output Directory" button is pushed, the user
            % selects a directory for the border GT (TODO: expand to
            % reverse problem?) where images will be saved when the
            % "Convert" button is pressed.
            
            if ~isempty(app.neuronInputDirectory)
                selectOutputDirectory = uigetdir(app.neuronInputDirectory, "Select Output Membrane Folder");
            else
                selectOutputDirectory = uigetdir(pwd, "Select Output Membrane Folder");
            end
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectOutputDirectory ~= 0
                app.membraneOutputDirectory = selectOutputDirectory;
                app.SelectMembraneOutputDirectoryButton.Text = strcat('Selected Output Directory: ', app.membraneOutputDirectory);
            end
            
             % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.neuronInputDirectory) && ~isempty(app.membraneOutputDirectory)
                app.NtoMConvertButton.Enable = 'on';
            end
            
        end

        % Button pushed function: NtoMConvertButton
        function NtoMConvertButtonPushed(app, event)
            % When the "Convert" button is pushed, the app reads the values
            % of the "Border Thickness" spinner, reads the images in the input directory,
            % processes them with erosion/dilation, and saves them to the
            % output directory
            
            % Get value of border thickness spinner
            app.borderThickness = app.borderThicknessSpinner.Value;
            
            % Read input directory images (PNG format)
            inputFilePattern = fullfile(app.neuronInputDirectory, '*.png');
            neuronGTFiles = dir(inputFilePattern);
            nFiles = length(neuronGTFiles);
            
            % Open a progress bar
            fig = uifigure;
            progressBar = uiprogressdlg(fig, 'Title', 'Please Wait', 'Message', 'Reading neuron ground truth images');
            
            for imgIndex = 1:nFiles
                currentfilename = neuronGTFiles(imgIndex).name;
                fullfilename = fullfile(app.neuronInputDirectory, currentfilename);
                currentimage = imread(fullfilename);
                neuronGT{imgIndex} = currentimage;
                progressBar.Value = imgIndex/(2*nFiles); % Update the value of the progress bar (finishing the Neuron GT is halfway)
            end
            
            % Convert each neuron GT image into border GT image and save it
            % to the output directory
            
            progressBar.Message = "Converting neuron ground truth to border ground truth";
            
            for imgIndex = 1:nFiles
                currentNeuronGT = neuronGT{imgIndex};
                
                if ndims(currentNeuronGT) == 3
                    grayNeuronGT = unique_rgb(currentNeuronGT); % Convert RGB image into grayscale image
                elseif ndims(currentNeuronGT) == 2
                    grayNeuronGT = currentNeuronGT;
                end
                
                structuringElement = ones(app.borderThickness, app.borderThickness);
                 
                newBorderGT = convertNeuronToMembrane(grayNeuronGT, structuringElement, app.FillExtracellularSpaceECSSwitch.Value); % Conversion to border GT
                
                % Show the first border GT in the preview axes                
                if imgIndex == 1
                    app.BorderPreviewAxes.Visible = 'on';
                    imshow(imadjust(newBorderGT), 'parent', app.BorderPreviewAxes)
                end
                
                borderFileName = strcat(erase(neuronGTFiles(imgIndex).name, ".png"), "_border.png");
                borderFullFileName = fullfile(app.membraneOutputDirectory, borderFileName);
                imwrite(newBorderGT, borderFullFileName);
                progressBar.Value = 0.5 + imgIndex/(2*nFiles);
            end
            
            close(progressBar)
            close(fig)
        end

        % Button pushed function: NtoMResetButton
        function NtoMResetButtonPushed(app, event)
            % When the "Reset" button is pushed, all app properties should
            % be emptied, Border Preview Axes are cleared and made
            % invisible, and "Convert" button is disabled
            
            app.neuronInputDirectory = [];
            app.membraneOutputDirectory = [];
            app.borderThickness = [];
            
            app.SelectNeuronInputDirectoryButton.Text = "Select Input Directory...";
            app.SelectMembraneOutputDirectoryButton.Text = "Select Output Directory...";
            
            cla(app.BorderPreviewAxes)
            app.BorderPreviewAxes.Visible = "off";
            
            app.NtoMConvertButton.Enable = "off";
            
            app.borderThicknessSpinner.Value = 3;
            
        end

        % Button pushed function: SelectMembraneInputDirectoryButton
        function SelectMembraneInputDirectoryButtonPushed(app, event)
            % When the "Select Input Directory" button is pushed, the user
            % selects a directory for the membrane GT (TODO: expand to
            % reverse problem?) whose images will be processed when the
            % "Convert" button is pressed.
            
            selectInputDirectory = uigetdir(pwd, "Select Input Membranes Folder");
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.membraneInputDirectory = selectInputDirectory;
                app.SelectMembraneInputDirectoryButton.Text = strcat('Selected Input Directory: ', app.membraneInputDirectory);
            end
            
            % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.membraneInputDirectory) && ~isempty(app.skeletonOutputDirectory)
                app.MtoSConvertButton.Enable = 'on';
            end
        end

        % Button pushed function: SelectSkeletonOutputDirectoryButton
        function SelectSkeletonOutputDirectoryButtonPushed(app, event)
            % When the "Select Output Directory" button is pushed, the user
            % selects a directory for the skeleton GT (TODO: expand to
            % reverse problem?) where images will be saved when the
            % "Convert" button is pressed.
            
            if ~isempty(app.membraneInputDirectory)
                selectOutputDirectory = uigetdir(app.membraneInputDirectory, "Select Skeleton Output Folder");
            else
                selectOutputDirectory = uigetdir(pwd, "Select Skeleton Output Folder");
            end
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectOutputDirectory ~= 0
                app.skeletonOutputDirectory = selectOutputDirectory;
                app.SelectSkeletonOutputDirectoryButton.Text = strcat('Selected Output Directory: ', app.skeletonOutputDirectory);
            end
            
             % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.membraneInputDirectory) && ~isempty(app.skeletonOutputDirectory)
                app.MtoSConvertButton.Enable = 'on';
            end
        end

        % Button pushed function: MtoSConvertButton
        function MtoSConvertButtonPushed(app, event)
            % When the "Convert" button is pushed, the app reads the values
            % of the "Membrane pixels are set to..." button group, reads the images 
            % in the input directory, processes them with bwskel, 
            % and saves them to the output directory

            % Check which toggle button for the size of the Gaussian filter
            % is selected
            switch app.sizeOneButton.Value
                case 0
                    switch app.sizeThreeButton
                        case 0
                            app.filterSize = 5;
                        case 1
                            app.filterSize = 3;
                    end
                otherwise
                    app.filterSize = 1;
            end
            
            % Check which toggle button for whether there is an unknown
            % zero pixel
            switch app.yesButton.Value
                case 1
                    app.zeroPixelUsed = true;
                otherwise
                    app.zeroPixelUsed = false;
            end
            
            % Read input directory images (PNG format)
            inputFilePattern = fullfile(app.membraneInputDirectory, '*.png');
            membraneGTFiles = dir(inputFilePattern);
            nFiles = length(membraneGTFiles);
            
            % Open a progress bar
            fig = uifigure;
            progressBar = uiprogressdlg(fig, 'Title', 'Please Wait', 'Message', 'Reading membrane ground truth images');
            
            for imgIndex = 1:nFiles
                currentfilename = membraneGTFiles(imgIndex).name;
                fullfilename = fullfile(app.membraneInputDirectory, currentfilename);
                currentimage = imread(fullfilename);
                membraneGT{imgIndex} = currentimage;
                progressBar.Value = imgIndex/(2*nFiles); % Update the value of the progress bar (finishing the Membrane GT is halfway)
            end
            
            % Convert each membrane GT image into skeleton GT image and save it
            % to the output directory
            
            progressBar.Message = "Converting membrane ground truth to skeleton ground truth";
            
            for imgIndex = 1:nFiles
                currentMembraneGT = membraneGT{imgIndex};
                
                if (app.zeroPixelUsed == true)
                    newSkeletonGT = convertMembraneToSkeleton_withZeroPixel(currentMembraneGT, app.filterSize);
                else
                    newSkeletonGT = convertMembraneToSkeleton_noZeroPixel(currentMembraneGT, app.filterSize);
                end
                

                % Show the first skeleton GT in the preview axes                 
                if imgIndex == 1
                    app.SkeletonPreviewAxes.Visible = 'on';
                    imshow(imadjust(newSkeletonGT), 'parent', app.SkeletonPreviewAxes)
                end
                
                skelFileName = strcat(erase(membraneGTFiles(imgIndex).name, ".png"), "_skel.png");
                skelFullFileName = fullfile(app.skeletonOutputDirectory, skelFileName);
                imwrite(newSkeletonGT, skelFullFileName);
                progressBar.Value = 0.5 + imgIndex/(2*nFiles);
            end
            
            close(progressBar)
            close(fig)
        end

        % Button pushed function: MtoSResetButton
        function MtoSResetButtonPushed(app, event)
            % When the "Reset" button is pushed, all app properties should
            % be emptied, Border Preview Axes are cleared and made
            % invisible, and "Convert" button is disabled
            
            app.membraneInputDirectory = [];
            app.skeletonOutputDirectory = [];
            app.borderThickness = [];
            
            app.SelectMembraneInputDirectoryButton.Text = "Select Input Directory...";
            app.SelectSkeletonOutputDirectoryButton.Text = "Select Output Directory...";
            
            cla(app.SkeletonPreviewAxes)
            app.SkeletonPreviewAxes.Visible = "off";
            
            app.MtoSConvertButton.Enable = "off";   

            app.noButton.Value = false;
            app.yesButton.Value = true;

            app.sizeFiveButton.Value = false;
            app.sizeThreeButton.Value = true;
            app.sizeOneButton.Value = false;
        end

        % Button pushed function: selectInputDirectory
        function selectInputDirectoryButtonPushed(app, event)
            % When the Select Input Directory... button is pushed, the user
            % can select an input directory where input data is loaded to be
            % divided into patches
            
            selectInputDirectory = uigetdir(pwd, 'Select Folder');
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.patchGenInputDirectory = selectInputDirectory;
                app.selectInputDirectory.Text = ['Selected Input Directory: ', newline, app.patchGenInputDirectory];
            end
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.patchGenInputDirectory) && ~isempty(app.patchGenInputPatchDirectory) && ~isempty(app.patchGenOutputDirectory) && ~isempty(app.patchGenOutputPatchDirectory)
                app.GeneratePatchesButton.Enable = 'on';
            end
            
        end

        % Button pushed function: selectInputPatchDirectory
        function selectInputPatchDirectoryButtonPushed(app, event)
            % When the Select Output (Patch) Directory... button is pushed, the user
            % can select an output directory where patches of the input is saved
            
            selectOutputDirectory = uigetdir(pwd, 'Select Patch Folder');
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectOutputDirectory ~= 0
                app.patchGenInputPatchDirectory = selectOutputDirectory;
                app.selectInputPatchDirectory.Text = ['Selected Output (Patch) Directory: ', newline, app.patchGenInputPatchDirectory];
            end 
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.patchGenInputDirectory) && ~isempty(app.patchGenInputPatchDirectory) && ~isempty(app.patchGenOutputDirectory) && ~isempty(app.patchGenOutputPatchDirectory)
                app.GeneratePatchesButton.Enable = 'on';
            end
        end

        % Button pushed function: selectLabelDirectory
        function selectLabelDirectoryButtonPushed(app, event)
            % When the Select Input Directory... button is pushed, the user
            % can select an input directory where label data is loaded to be
            % divided into patches
            
            if app.patchGenInputDirectory ~= 0
                selectInputDirectory = uigetdir(app.patchGenInputDirectory, 'Select Label Folder');
            else
                selectInputDirectory = uigetdir(pwd, 'Select Label Folder');
            end
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.patchGenOutputDirectory = selectInputDirectory;
                app.selectLabelDirectory.Text = ['Selected Input Directory: ', newline, app.patchGenOutputDirectory];
            end 
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.patchGenInputDirectory) && ~isempty(app.patchGenInputPatchDirectory) && ~isempty(app.patchGenOutputDirectory) && ~isempty(app.patchGenOutputPatchDirectory)
                app.GeneratePatchesButton.Enable = 'on';
            end
        end

        % Button pushed function: selectLabelPatchDirectory
        function selectLabelPatchDirectoryButtonPushed(app, event)
            % When the Select Output (Patch) Directory... button is pushed, the user
            % can select an output directory where patches of the label data 
            % is loaded to be divided into patches
            
            if app.patchGenInputPatchDirectory ~= 0
                selectOutputDirectory = uigetdir(app.patchGenInputPatchDirectory, 'Select Patch Label Folder');
            else
                selectOutputDirectory = uigetdir(pwd, 'Select Patch Label Folder');
            end
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectOutputDirectory ~= 0
                app.patchGenOutputPatchDirectory = selectOutputDirectory;
                app.selectLabelPatchDirectory.Text = ['Selected Output (Patch) Directory: ', newline, app.patchGenOutputPatchDirectory];
            end 
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.patchGenInputDirectory) && ~isempty(app.patchGenInputPatchDirectory) && ~isempty(app.patchGenOutputDirectory) && ~isempty(app.patchGenOutputPatchDirectory)
                app.GeneratePatchesButton.Enable = 'on';
            end
        end

        % Value changed function: PatchSizeEditField
        function PatchSizeEditFieldValueChanged(app, event)
            % When the value of the patch size edit field is changed, the
            % accompanying label is modified
        
            value = app.PatchSizeEditField.Value;
            
            app.pixelsbypixelsLabel.Text = sprintf("pixels by %d pixels", value);
            app.patchGenPatchSize = value;
        end

        % Button pushed function: GeneratePatchesButton
        function GeneratePatchesButtonPushed(app, event)
            % When the Generate Patches button is clicked, the parameters'
            % values are read and the data is pre-processed and divided
            % into the requested patches
            
            tic
            
            app.patchGenPatchSize = app.PatchSizeEditField.Value;
            app.patchGenPatchDensity = app.PatchDensityEditField.Value;
            
            if  (app.PatchSizeEditField.Value ~= 0) && (app.PatchDensityEditField.Value ~= 0)
            
            % Read input and label directory images (PNG format)
            inputFilePattern = fullfile(app.patchGenInputDirectory, '*.png');
            inputFiles = dir(inputFilePattern);
            nInputFiles = length(inputFiles);
            
            outputFilePattern = fullfile(app.patchGenOutputDirectory, "*.png");
            outputFiles = dir(outputFilePattern);
            nOutputFiles = length(outputFiles);
            
            for imgIndex = 1:nInputFiles
                sprintf('Image %d is being processed', imgIndex)
                [~, currentInputFilename, ~] = fileparts(inputFiles(imgIndex).name);
                fullInputFilename = fullfile(app.patchGenInputDirectory, inputFiles(imgIndex).name);
                
                [~, currentOutputFilename, ~] = fileparts(outputFiles(imgIndex).name);
                fullOutputFilename = fullfile(app.patchGenOutputDirectory, outputFiles(imgIndex).name);
                
                currentInputImage = imread(fullInputFilename);
                currentOutputImage = imread(fullOutputFilename);
                
                patchGenerationPreprocessing(currentInputImage, currentOutputImage,...
                    currentInputFilename, currentOutputFilename, app.patchGenInputPatchDirectory,...
                    app.patchGenOutputPatchDirectory, app.patchGenPatchDensity, app.patchGenPatchSize,...
                    app.patchGenContrastCorrect, app.patchGenDownsamplingFactor);
                
            end
            toc
            
            elseif  app.PatchSizeEditField.Value == 0 || app.PatchDensityEditField.Value == 0
                errordlg("Patch size and density should be nonzero", "Missing parameters")
            end

        end

        % Button pushed function: advancedSettingsButton
        function advancedSettingsButtonPushed(app, event)
            % When the Advanced Settings button is pushed, a window with
            % additional parameters pops up
            
            app.advancedSettingsButton.Enable = "off";
            app.AdvancedSettingsApp = preprocessingAdvancedSettings(app);
            
        end

        % Button pushed function: PatchesResetButton
        function PatchesResetButtonPushed(app, event)
            % When the "Reset" button is pushed, all app properties should
            % be emptied, Preview Axes are cleared and made
            % invisible, and "Generate Patches" button is disabled
            
            app.patchGenInputDirectory = [];
            app.patchGenInputPatchDirectory = [];
            app.patchGenOutputDirectory= [];
            app.patchGenOutputPatchDirectory = [];
            app.patchGenPatchSize = [];
            app.patchGenPatchDensity = [];
            app.patchGenContrastCorrect = [];
            app.patchGenDownsamplingFactor = [];
            
            app.selectInputDirectory.Text = "Select Input Directory...";
            app.selectInputPatchDirectory.Text = "Select Output (Patch) Directory...";
            app.selectLabelDirectory.Text = "Select Input Directory...";
            app.selectLabelPatchDirectory.Text = "Select Output (Patch) Directory...";
            app.PatchSizeEditField.Value = 0;
            app.PatchDensityEditField.Value = 0;
            
            app.GeneratePatchesButton.Enable = "off"; 
        end

        % Button pushed function: selectInputDataDirectoryButton
        function selectInputDataDirectoryButtonPushed(app, event)
            % When the "Select Input Directory" is pushed, the user will
            % select the directory where the deep learning training input
            % data will come from
            
            if ~isempty(app.patchGenInputPatchDirectory)
                selectInputDirectory = uigetdir(app.patchGenInputPatchDirectory, 'Select Input Dataset Folder');
            else
                selectInputDirectory = uigetdir(pwd, 'Select Input Dataset Folder');
            end
            
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.deepInputDirectory = selectInputDirectory;
                app.selectInputDataDirectoryButton.Text = ['Selected Input Directory: ', newline, app.deepInputDirectory];
            end 
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.deepInputDirectory) && ~isempty(app.deepLabelDirectory) && ~isempty(app.deepSaveNetworkDirectory)
                app.TrainNetworkButton.Enable = 'on';
            end
        end

        % Button pushed function: selectLabelDataDirectoryButton
        function selectLabelDataDirectoryButtonPushed(app, event)
            % When the "Select Label Directory" is pushed, the user will
            % select the directory where the deep learning training label
            % data will come from
            
            if ~isempty(app.patchGenOutputPatchDirectory)
                selectInputDirectory = uigetdir(app.patchGenOutputPatchDirectory, "Select Ground Truth Dataset Folder");
            else
                selectInputDirectory = uigetdir(pwd, "Select Ground Truth Dataset Folder");
            end
            
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.deepLabelDirectory = selectInputDirectory;
                app.selectLabelDataDirectoryButton.Text = ['Selected Label Directory: ', newline, app.deepLabelDirectory];
            end 
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.deepInputDirectory) && ~isempty(app.deepLabelDirectory) && ~isempty(app.deepSaveNetworkDirectory)
                app.TrainNetworkButton.Enable = 'on';
            end
        end

        % Button pushed function: selectTrainedNetworkDirectoryButton
        function selectTrainedNetworkDirectoryButtonPushed(app, event)
            % When the "Select Trained Network Directory" is pushed, the user will
            % select the directory where the trained neural network will
            % be saved 
            
            if ~isempty(app.deepSaveNetworkDirectory)
                selectTrainedNetworkDirectory = uigetdir(app.deepSaveNetworkDirectory, "Select Trained Network Directory");
            else
                selectTrainedNetworkDirectory = uigetdir(pwd, "Select Trained Network Directory");
            end
            
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectTrainedNetworkDirectory ~= 0
                app.deepSaveNetworkDirectory = selectTrainedNetworkDirectory;
                app.selectTrainedNetworkDirectoryButton.Text = ['Selected Trained Network Directory: ', newline, app.deepSaveNetworkDirectory];
            end 
            
            % Check if all directories have been selected, and if they are,
            % enable the Generate Patches button
            
            if ~isempty(app.deepInputDirectory) && ~isempty(app.deepLabelDirectory) && ~isempty(app.deepSaveNetworkDirectory)
                app.TrainNetworkButton.Enable = 'on';
            end
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            % When the "Train Network" button is pushed, check the status
            % of all options to accordingly create the advancedSettings
            % struct and save the trained network from deepLearningTraining
            
            % Advanced Settings - Net Depth
            
            advancedSettings.netDepth = app.UNETnetworkdepthEditField.Value;
            
            % Advanced Settings - Optimizer
            
            advancedSettings.optimizer = app.TrainingoptimizerDropDown.Value;
            
            % Advanced Settings - Learning Rate
            
            advancedSettings.learningRate = app.InitialLearningRateEditField.Value;
            
            % Advanced Settings - Shuffle
            
            advancedSettings.shuffle = app.ShuffletrainingdataSwitch.Value;
            
            % Advanced Settings - Verbose
            
            advancedSettings.verbose = str2num(app.PrinttrainingprogressSwitch.Value);
            
            % Advanced Settings - Plot Training
            
            advancedSettings.plotTraining = app.PlottrainingprogressSwitch.Value;
            
            % Advanced Settings - Learning Rate Schedule
            
            advancedSettings.learnRateSchedule = app.DroplearningrateduringtrainingSwitch.Value;
            
            % Advanced Settings - Learning Rate Drop Factor
            
            advancedSettings.learnRateDropFactor = app.ByhowmuchEditField.Value;
            
            % Advanced Settings - Training-Validation Split
            
            advancedSettings.trainingPercent = app.TrainingValidationSplitTrainingDataSlider.Value;
            
            % Train network
            
            deepLearningTraining(app.deepInputDirectory, app.deepLabelDirectory, app.deepImportedNetwork, app.deepSaveNetworkDirectory, app.numOfEpochsSpinner.Value, app.numOfClassesSpinner.Value, app.NetworkNameEditField.Value, app.SavenetworkasDropDown.Value, advancedSettings);
            
        end

        % Selection changed function: networkButtonGroup
        function networkButtonGroupSelectionChanged(app, event)
            selectedButton = app.networkButtonGroup.SelectedObject;
            
            if selectedButton == app.importNetworkButton
                [selectNetworkFile, selectNetworkPath] = uigetfile;
                drawnow;
                figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
                if selectNetworkFile ~= 0
                    app.deepImportedNetwork = fullfile(selectNetworkPath, selectNetworkFile);
                    app.importNetworkButton.Text = ['Selected Network Directory: ', newline, app.deepImportedNetwork];
                else
                    app.unetButton.Value = 1;
                    app.importNetworkButton.Value = 0;
                end
            else
                app.deepImportedNetwork = [];
                app.importNetworkButton.Text = "Import Neural Network";
            end
        end

        % Value changed function: DroplearningrateduringtrainingSwitch
        function DroplearningrateduringtrainingSwitchValueChanged(app, event)
            % Enable learning rate drop edit field when learn rate schedule switch is on
        
            value = string(app.DroplearningrateduringtrainingSwitch.Value);
            
            if value == 'piecewise'
                app.ByhowmuchEditField.Enable = "on";
                app.ByhowmuchEditFieldLabel.Enable = "on";
            else
                app.ByhowmuchEditField.Enable = "off";
                app.ByhowmuchEditFieldLabel.Enable = "off";
            end
        end

        % Value changing function: 
        % TrainingValidationSplitTrainingDataSlider
        function TrainingValidationSplitTrainingDataSliderValueChanging(app, event)
            % Update training split edit field when the slider value is
            % changed.
        
            changingValue = event.Value;
            
            app.trainingSplitEditField.Value = changingValue;
            
        end

        % Value changed function: trainingSplitEditField
        function trainingSplitEditFieldValueChanged(app, event)
            % Update training split slider when edit field value is
            % changed.
        
            value = app.trainingSplitEditField.Value;
            
            app.TrainingValidationSplitTrainingDataSlider.Value = value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset Deep Learning Interface
            
            app.deepInputDirectory = [];
            app.deepLabelDirectory = [];
            app.deepImportedNetwork = [];
            app.deepSaveNetworkDirectory = [];
            
            app.selectInputDataDirectoryButton.Text = "Select Input Directory...";
            app.selectLabelDataDirectoryButton.Text = "Select Label Directory...";
            app.importNetworkButton.Text = "Import Neural Network";
            app.selectTrainedNetworkDirectoryButton.Text = "Select Trained Network Directory...";
            
            app.NetworkNameEditField.Value = "";
            app.numOfEpochsSpinner.Value = 10;
            app.numOfClassesSpinner.Value = 2;
            app.SavenetworkasDropDown.Value = "mat";
            app.UNETnetworkdepthEditField.Value = 5;
            app.ShuffletrainingdataSwitch.Value = "every-epoch";
            app.InitialLearningRateEditField.Value = 0.00005;
            app.DroplearningrateduringtrainingSwitch.Value = "none";
            app.ByhowmuchEditField.Value = 0.1;
            app.TrainingValidationSplitTrainingDataSlider.Value = 70;
            app.trainingSplitEditField.Value = 70;
            app.PrinttrainingprogressSwitch.Value = '1';
            app.PlottrainingprogressSwitch.Value = 'training-progress';
            
            app.TrainNetworkButton.Enable = "Off";
            
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % When the Predict button is pushed, evaluate the trained
            % network and display the predictions
             
            allPredictions = [];
            
            if ischar(app.predictInputImages)
                app.predictInputImages = {app.predictInputImages};
            end

            for inputImage = 1:size(app.predictInputImages,2)
                image = imread(string(app.predictInputImages{inputImage}));
                predictions = deepLearningPredict(image, app.predictNetwork);
                
                allPredictions = [allPredictions, predictions];
            end
            
            if app.ShowInputDataSwitch.Value == "On"
                if app.showPredictionsButton.Value == 1
                    imshow(allPredictions(1).predictionWithInput, 'parent', app.PredictionPreviewAxes)
                else
                    imshow(allPredictions(1).probabilityWithInput, 'parent', app.PredictionPreviewAxes)
                end
            else
                if app.showPredictionsButton.Value == 1
                    imshow(allPredictions(1).prediction, 'parent', app.PredictionPreviewAxes)
                else
                    imshow(allPredictions(1).probability, 'parent', app.PredictionPreviewAxes)
                end
            end
                
            app.PreviousPredictButton.Enable = 1;
            app.NextPredictButton.Enable = 1;
            app.savePredictionsToButton.Enable = 1;
            
            app.predictions = allPredictions;
            app.currentPredictIndex = 1;
            
            app.currentPreviewPathLabel.Text = app.predictInputImages{1};
        end

        % Button pushed function: selectPredictionNetworkButton
        function selectPredictionNetworkButtonPushed(app, event)
            % Select a .mat file from which the prediction network will be
            % imported
            
            [selectNetworkFile, selectNetworkPath] = uigetfile;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            
            if selectNetworkFile ~= 0
                app.predictNetwork = getfield(load(fullfile(selectNetworkPath, selectNetworkFile)), "net");
                app.selectPredictionNetworkButton.Text = ['Selected Network Path: ', fullfile(selectNetworkPath, selectNetworkFile)]; 
            end
            
            if ~isempty(app.predictNetwork) && ~isempty(app.predictInputImages)
                app.PredictButton.Enable = 1;
            end
        end

        % Button pushed function: selectPredictionInputButton
        function selectPredictionInputButtonPushed(app, event)
            % Select one or multiple images as input images for prediction
            
            [selectedImageFiles, selectedImagePaths] = uigetfile("*", 'MultiSelect', "on");
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            
            if ~iscell(selectedImageFiles)
                numFiles = 1;
                if ~isempty(selectedImageFiles)
                   app.predictInputImages = fullfile(selectedImagePaths, selectedImageFiles); 
                end
            else 
                numFiles = numel(selectedImageFiles);
                app.predictInputImages = fullfile(selectedImagePaths, selectedImageFiles); 
            end
              
            app.selectPredictionInputButton.Text = strcat(num2str(numFiles), " images selected");
            
            if ~isempty(app.predictNetwork) && ~isempty(app.predictInputImages)
                app.PredictButton.Enable = 1;
            end
        end

        % Button pushed function: ResetPredictButton
        function ResetPredictButtonPushed(app, event)
            % Reset prediction network, input images, and preview axes
            
            app.predictNetwork = [];
            app.predictInputImages = [];
            app.currentPredictIndex = [];
            
            cla(app.PredictionPreviewAxes);
            app.PredictButton.Enable = 0;
            app.PreviousPredictButton.Enable = 0;
            app.NextPredictButton.Enable = 0;
            app.savePredictionsToButton.Enable = 0;
            
            app.selectPredictionNetworkButton.Text = "Select Trained Network...";
            app.selectPredictionInputButton.Text = "Select input images";
        end

        % Value changed function: ShowInputDataSwitch
        function ShowInputDataSwitchValueChanged(app, event)
            value = app.ShowInputDataSwitch.Value;
            
            if value == "On"
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).predictionWithInput, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probabilityWithInput, 'parent', app.PredictionPreviewAxes)
                end
            else
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).prediction, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probability, 'parent', app.PredictionPreviewAxes)
                end
            end
            
        end

        % Selection changed function: predictDisplayButtonGroup
        function predictDisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.predictDisplayButtonGroup.SelectedObject;
            
            if app.ShowInputDataSwitch.Value == "On"
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).predictionWithInput, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probabilityWithInput, 'parent', app.PredictionPreviewAxes)
                end
            else
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).prediction, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probability, 'parent', app.PredictionPreviewAxes)
                end
            end
        end

        % Button pushed function: NextPredictButton
        function NextPredictButtonPushed(app, event)
            % Advance prediction preview
            
            app.currentPredictIndex = mod(app.currentPredictIndex + 1, size(app.predictions, 2));
            
            if app.currentPredictIndex == 0
                app.currentPredictIndex = 1;
            end
            
            if app.ShowInputDataSwitch.Value == "On"
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).predictionWithInput, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probabilityWithInput, 'parent', app.PredictionPreviewAxes)
                end
            else
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).prediction, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probability, 'parent', app.PredictionPreviewAxes)
                end
            end
            
            app.currentPreviewPathLabel.Text = app.predictInputImages{app.currentPredictIndex};
            
        end

        % Button pushed function: PreviousPredictButton
        function PreviousPredictButtonPushed(app, event)
            % Go back in prediction preview
            
            app.currentPredictIndex = app.currentPredictIndex - 1;
            
            if app.currentPredictIndex == 0
                app.currentPredictIndex = size(app.predictions, 2);
            end
            
            if app.ShowInputDataSwitch.Value == "On"
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).predictionWithInput, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probabilityWithInput, 'parent', app.PredictionPreviewAxes)
                end
            else
                if app.showPredictionsButton.Value == 1
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).prediction, 'parent', app.PredictionPreviewAxes)
                else
                    cla(app.PredictionPreviewAxes);
                    imshow(app.predictions(app.currentPredictIndex).probability, 'parent', app.PredictionPreviewAxes)
                end
            end
            
            app.currentPreviewPathLabel.Text = app.predictInputImages{app.currentPredictIndex};
        end

        % Button pushed function: savePredictionsToButton
        function savePredictionsToButtonPushed(app, event)
            selectPredictSaveDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            
            [~, newName, ~] = fileparts(app.predictInputImages{app.currentPredictIndex});
            
            if app.ShowInputDataSwitch.Value == "On"
                if app.showPredictionsButton.Value == 1
                    imwrite(app.predictions(app.currentPredictIndex).predictionWithInput, fullfile(selectPredictSaveDirectory, strcat(newName, "_prediction.png")));
                else
                    imwrite(app.predictions(app.currentPredictIndex).probabilityWithInput, fullfile(selectPredictSaveDirectory, strcat(newName, "_prediction.png")));
                end
            else
                if app.showPredictionsButton.Value == 1
                    imwrite(app.predictions(app.currentPredictIndex).prediction, fullfile(selectPredictSaveDirectory, strcat(newName, "_prediction.png")));
                else
                    imwrite(app.predictions(app.currentPredictIndex).probability, fullfile(selectPredictSaveDirectory, strcat(newName, "_prediction.png")));
                end
            end
        end

        % Callback function
        function WhatwouldyouliketodoButtonGroupSelectionChanged(app, event)
            selectedButton = app.WhatwouldyouliketodoButtonGroup.SelectedObject;
            
            if selectedButton == app.PredictToggleButton
                app.PredictPanel.Visible = "On";
                app.CorrectPredictionsPanel.Visible = "Off";
            elseif selectedButton == app.CorrectPredictionsToggleButton
                app.PredictPanel.Visible = "Off";
                app.CorrectPredictionsPanel.Visible = "On";
            end    
                            
        end

        % Button pushed function: ConnectToVASTButton
        function ConnectToVASTButtonPushed(app, event)
            % Connect to VAST via API
            
            vastControlDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if vastControlDirectory ~= 0
                assignin('base', 'port', app.PortEditField.Value);
                assignin('base', 'vastControlPath', vastControlDirectory);
                evalin('base', 'connectToVAST');
                
                app.vast = evalin('base', 'vast');
                
                vastElementsLeft = get(app.GeneratePredictionsfromEMVSVIPanel, 'Children');
                set(findall(vastElementsLeft, '-property', 'Enable'), 'Enable', 'On');
                vastElementsRight = get(app.ConvertVSVItoVSSEGPanel, 'Children');
                set(findall(vastElementsRight, '-property', 'Enable'), 'Enable', 'On');
                
                app.XStartEditField.Enable = "Off";
                app.toLabel.Enable = "Off";
                app.XEndEditField.Enable = "Off";
                app.XFullrangeCheckBox.Enable = "Off";
                app.YStartEditField.Enable = "Off";
                app.toLabel_2.Enable = "Off";
                app.YEndEditField.Enable = "Off";
                app.YFullrangeCheckBox.Enable = "Off";
                app.ZStartEditField.Enable = "Off";
                app.toLabel_3.Enable = "Off";
                app.ZEndEditField.Enable = "Off";
                app.ZFullrangeCheckBox.Enable = "Off";
                app.XLabel.Enable = "Off";
                app.YLabel.Enable = "Off";
                app.ZLabel.Enable = "Off";
            end
            
            [nroflayers, res] = app.vast.getnroflayers()

            app.vastLayers = {};
            
            if res == 1
               for i = 0:nroflayers-1
                   [linfo res] = app.vast.getlayerinfo(i);
                   layerName = getfield(linfo, "name");
                   app.vastLayers{end + 1} = layerName;
               end
            end

            app.ImageLayerDropDown.Items = ["Select a VAST Layer" app.vastLayers];
            app.ConversionImageLayerDropDown.Items = ["Select a VAST Layer" app.vastLayers];
            app.ConversionSegLayerDropDown.Items = ["Select a VAST Layer" app.vastLayers];
            app.ConversionAnnoLayerDropDown.Items = ["Select a VAST Layer" app.vastLayers];
            app.ConversionSourceAnnoLayerDropDown.Items = ["Select a VAST Layer" app.vastLayers];
            
        end

        % Selection changed function: PredictionRangeButtonGroup
        function PredictionRangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PredictionRangeButtonGroup.SelectedObject;
            
            if selectedButton == app.PartofVolumeButton
                app.XStartEditField.Enable = "On";
                app.toLabel.Enable = "On";
                app.XEndEditField.Enable = "On";
                app.XFullrangeCheckBox.Enable = "On";
                app.YStartEditField.Enable = "On";
                app.toLabel_2.Enable = "On";
                app.YEndEditField.Enable = "On";
                app.YFullrangeCheckBox.Enable = "On";
                app.ZStartEditField.Enable = "On";
                app.toLabel_3.Enable = "On";
                app.ZEndEditField.Enable = "On";
                app.ZFullrangeCheckBox.Enable = "On";
                app.XLabel.Enable = "On";
                app.YLabel.Enable = "On";
                app.ZLabel.Enable = "On";
            else
                app.XStartEditField.Enable = "Off";
                app.toLabel.Enable = "Off";
                app.XEndEditField.Enable = "Off";
                app.XFullrangeCheckBox.Enable = "Off";
                app.YStartEditField.Enable = "Off";
                app.toLabel_2.Enable = "Off";
                app.YEndEditField.Enable = "Off";
                app.YFullrangeCheckBox.Enable = "Off";
                app.ZStartEditField.Enable = "Off";
                app.toLabel_3.Enable = "Off";
                app.ZEndEditField.Enable = "Off";
                app.ZFullrangeCheckBox.Enable = "Off";
                app.XLabel.Enable = "Off";
                app.YLabel.Enable = "Off";
                app.ZLabel.Enable = "Off";
            end
            
        end

        % Button pushed function: TrainedNetworkPredictionSelectButton
        function TrainedNetworkPredictionSelectButtonPushed(app, event)
            [file, path] = uigetfile;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if ~isempty(file)
                app.vastPredictionsTrainedNetwork = fullfile(path, file);
                app.TrainedNetworkPredictionSelectButton.Text = ['Selected Trained Network Directory: ', newline, app.vastPredictionsTrainedNetwork];
            end
        end

        % Button pushed function: PredictFromVSVIButton
        function PredictFromVSVIButtonPushed(app, event)
            % Load the network
            net = load(fullfile(app.vastPredictionsTrainedNetwork));

            if app.WholeVolumeButton.Value == 1
                [density_mat, density_peak_location_mat] = predict(net, app.vastPredictionsPredictionDirectory, app.vastPredictionsProbabilitiesDirectory, find(strcmp(app.vastLayers, app.ImageLayerDropDown.Value)) - 1, [], [], [], [], [], [], app.NumPredictionClassesSpinner.Value, [], app.MIPLevelSpinner.Value, app.GenerateVSVICheckBox.Value);
            elseif app.PartofVolumeButton.Value == 1
                 if app.XFullrangeCheckBox.Value == 1
                    x_init = [];
                    x_end = [];
                else
                    x_init = app.XStartEditField.Value;
                    x_end = app.XEndEditField.Value;
                end
    
                if app.YFullrangeCheckBox.Value == 1
                    y_init = [];
                    y_end = [];
                else
                    y_init = app.YStartEditField.Value;
                    y_end = app.YEndEditField.Value;
                end
    
                if app.ZFullrangeCheckBox.Value == 1
                    z_init = [];
                    z_end = [];
                else
                    z_init = app.ZStartEditField.Value;
                    z_end = app.ZEndEditField.Value;
                end

                [density_mat, density_peak_location_mat] = predict(net, app.vastPredictionsPredictionDirectory, app.vastPredictionsProbabilitiesDirectory, find(strcmp(app.vastLayers, app.ImageLayerDropDown.Value)) - 1, x_init, x_end, y_init, y_end, z_init, z_end, app.NumPredictionClassesSpinner.Value, [], app.MIPLevelSpinner.Value, app.GenerateVSVICheckBox.Value);
            elseif app.SkeletonButton.Value == 1
                [nroflayers, res] = vast.getnroflayers();
    
                for layer_num=1:nroflayers
                    [linfo, res] = vast.getlayerinfo(layer_num-1); % zero indexed
                    layernames{layer_num} = linfo.name;
                end

                % user inputs
                [skel_layer, tf] = listdlg('ListString', layernames,'PromptString','Select Segmentation Layer with Frames','Name','Vast Layers');
                
                %%% enable api layers
                [~] = app.vast.setapilayersenabled(1);
                %%% read from layer 1
                [~] = app.vast.setselectedapilayernr(skel_layer-1);
                
                vinfo = app.vast.getinfo();
                size_x = double(vinfo.datasizex);
                size_y = double(vinfo.datasizey);
                size_z = double(vinfo.datasizez);
                
                %%% Import annotation info
                [annonr, res] = app.vast.getannolayernrofobjects();
                [annonames, res] = app.vast.getannolayerobjectnames();
                [annodata, res] = app.vast.getannolayerobjectdata();
                coords = zeros(1, 3);
                
                %%% input the dimensions of the predicting window
                dimStr = inputdlg('dimensions of the prediction window','window dimension');
                window_dimension = str2double(dimStr{1}); %the anchor point of the skeleton will be in the center of the predicted window (x,y,z)
                zStr = inputdlg('z depth of the prediction window','z depth');
                zdepth = str2double(zStr{1});
                half_window_xy = ceil(window_dimension/2);
                half_window_z = ceil(zdepth/2);

                for iskel = 1:annonr
                    coords = annodata(iskel, 12:14)
                    [~] = app.vast.setviewcoordinates(coords(1), coords(2), coords(3));
                    i_init = coords(1) - half_window_xy;
                    if i_init < 0
                        i_init = 0;
                    end
                    i_final = coords(1) + half_window_xy;
                    if i_final > size_x
                        i_final = size_x;
                    end
                    j_init = coords(2) - half_window_xy;
                    if j_init < 0
                        j_init = 0;
                    end
                    j_final = coords(2) + half_window_xy;
                    if j_final > size_y
                        j_final = size_y;
                    end
                    k_init = coords(3) - half_window_z;
                    if k_init < 0
                        k_init = 0;
                    end
                    k_final = coords(3) + half_window_z;
                    if k_final > size_z
                        k_final = size_z;
                    end
                    [density_mat, density_peak_location_mat] = predict(net, app.vastPredictionsPredictionDirectory, app.vastPredictionsProbabilitiesDirectory, find(strcmp(app.vastLayers, app.ImageLayerDropDown)), i_init, i_final, j_init, j_final, k_init, k_final, app.NumPredictionClassesSpinner.Value, [], app.MipLevelSpinner.Value, app.GenerateVSVICheckBox.Value);
                end
            end

            save('density_matrix', 'density_mat');
            save('density_peak_location_matrix', 'density_peak_location_mat');

        end

        % Button pushed function: SelectPredDirectoryButton
        function SelectPredDirectoryButtonPushed(app, event)
            selectPredictionDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectPredictionDirectory ~= 0
                app.vastPredictionsPredictionDirectory = selectPredictionDirectory;
                app.SelectPredDirectoryButton.Text = ['Selected Predictions Directory: ', newline, app.vastPredictionsPredictionDirectory];
            end 
        end

        % Button pushed function: SelectProbDirectoryButton
        function SelectProbDirectoryButtonPushed(app, event)
            selectProbabilitiesDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectProbabilitiesDirectory ~= 0
                app.vastPredictionsProbabilitiesDirectory = selectProbabilitiesDirectory;
                app.SelectProbDirectoryButton.Text = ['Selected Probabilities Directory: ', newline, app.vastPredictionsProbabilitiesDirectory];
            end 
        end

        % Selection changed function: VSSEGLocationMethodButtonGroup
        function VSSEGLocationMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.VSSEGLocationMethodButtonGroup.SelectedObject;
            if selectedButton == app.RandomButton
                app.ConversionXStartEditField.Enable = "On";
                app.ConversionXEndEditField.Enable = "On";
                app.ConversionYStartEditField.Enable = "On";
                app.ConversionYEndEditField.Enable = "On";
                app.ConversionZStartEditField.Enable = "On";
                app.ConversionEndEditField.Enable = "On";
                app.toLabel_6.Enable = "On";
                app.toLabel_5.Enable = "On";
                app.toLabel_4.Enable = "On";
                app.XLabel_2.Enable = "On";
                app.YLabel_2.Enable = "On";
                app.ZLabel_2.Enable = "On";

                app.XLabel.Enable = "On";
                app.YLabel.Enable = "On";
                app.ZLabel.Enable = "On";

                app.ConversionSourceAnnoLayerDropDown.Enable = "Off";
                app.SourceAnnotationLayerLabel.Enable = "Off";
            else
                app.ConversionXStartEditField.Enable = "Off";
                app.ConversionXEndEditField.Enable = "Off";
                app.ConversionYStartEditField.Enable = "Off";
                app.ConversionYEndEditField.Enable = "Off";
                app.ConversionZStartEditField.Enable = "Off";
                app.ConversionEndEditField.Enable = "Off";
                app.toLabel_6.Enable = "Off";
                app.toLabel_5.Enable = "Off";
                app.toLabel_4.Enable = "Off";
                app.XLabel_2.Enable = "Off";
                app.YLabel_2.Enable = "Off";
                app.ZLabel_2.Enable = "Off";

                app.XLabel.Enable = "Off";
                app.YLabel.Enable = "Off";
                app.ZLabel.Enable = "Off";

                app.ConversionSourceAnnoLayerDropDown.Enable = "On";
                app.SourceAnnotationLayerLabel.Enable = "On";
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            locationMethod = app.VSSEGLocationMethodButtonGroup.SelectedObject;
            if locationMethod == app.SourceSkeletonButton_2
                srcAnnoLayerNr = find(strcmp(app.vastLayers, app.ConversionSourceAnnoLayerDropDown.Value));
                locationMethod = 'FromVSSANOOFILE';
            else
                srcAnnoLayerNr = [];
                locationMethod = 'RANDOM';
            end

            if app.ConversionXFullRangeCheckbox.Value == 1
                x_init = [];
                x_end = [];
            else
                x_init = app.ConversionXStartEditField.Value;
                x_end = app.ConversionXEndEditField.Value;
            end

            if app.ConversionYFullRangeCheckbox.Value == 1
                y_init = [];
                y_end = [];
            else
                y_init = app.ConversionYStartEditField.Value;
                y_end = app.ConversionXEndEditField.Value;
            end

            if app.ConversionZFullRangeCheckbox.Value == 1
                z_init = [];
                z_end = [];
            else
                z_init = app.ConversionZStartEditField.Value;
                z_end = app.ConversionZEndEditField.Value;
            end

            vsvi_to_vsseg(app.ConversionMIPLevelSpinner.Value, ...
                find(strcmp(app.vastLayers, app.ConversionImageLayerDropDown.Value))-1,...
                find(strcmp(app.vastLayers, app.ConversionSegLayerDropDown.Value))-1,...
                find(strcmp(app.vastLayers, app.ConversionAnnoLayerDropDown.Value))-1,...
                srcAnnoLayerNr, x_init, x_end, y_init, y_end, z_init, z_end,...
                locationMethod, app.ConversionTileSizeEditField.Value,...
                app.ConversionThresholdEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mEMbrainUIFigure = uifigure('Visible', 'off');
            app.mEMbrainUIFigure.Name = 'mEMbrain';
            app.TabGroup = uitabgroup(app.mEMbrainUIFigure);
            app.NeurontoMembraneTab = uitab(app.TabGroup);
            app.NeurontoMembraneTab.Title = 'Neuron to Membrane';
            app.BorderPreviewAxes = uiaxes(app.NeurontoMembraneTab);
            title(app.BorderPreviewAxes, 'Title')
            xlabel(app.BorderPreviewAxes, 'X')
            ylabel(app.BorderPreviewAxes, 'Y')
            app.BorderPreviewAxes.Visible = 'off';
            app.ConvertNeuronGroundTruthDatatoMembraneGroundTruthDataLabel = uilabel(app.NeurontoMembraneTab);
            app.ConvertNeuronGroundTruthDatatoMembraneGroundTruthDataLabel.Text = 'Convert Neuron Ground Truth Data to Membrane Ground Truth Data';
            app.NtoMDescriptionLabel = uilabel(app.NeurontoMembraneTab);
            app.NtoMDescriptionLabel.Text = 'Please select the ground truth directory to convert the neuron data to membrane data';
            app.NeuronGroundTruthLabel = uilabel(app.NeurontoMembraneTab);
            app.NeuronGroundTruthLabel.Text = 'Neuron Ground Truth';
            app.SelectNeuronInputDirectoryButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.SelectNeuronInputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNeuronInputDirectoryButtonPushed, true);
            app.SelectNeuronInputDirectoryButton.Text = 'Select Input Directory...';
            app.MembraneGroundTruthLabel = uilabel(app.NeurontoMembraneTab);
            app.MembraneGroundTruthLabel.Text = 'Membrane Ground Truth';
            app.SelectMembraneOutputDirectoryButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.SelectMembraneOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMembraneOutputDirectoryButtonPushed, true);
            app.SelectMembraneOutputDirectoryButton.Text = 'Select Output Directory...';
            app.MembranePropertiesLabel = uilabel(app.NeurontoMembraneTab);
            app.MembranePropertiesLabel.Text = 'Membrane Properties';
            app.SetmembranethicknesstoLabel = uilabel(app.NeurontoMembraneTab);
            app.SetmembranethicknesstoLabel.Text = 'Set membrane thickness to...';
            app.borderThicknessSpinner = uispinner(app.NeurontoMembraneTab);
            app.borderThicknessSpinner.Limits = [1 Inf];
            app.borderThicknessSpinner.Value = 3;
            app.MembraneGroundTruthPreviewLabel = uilabel(app.NeurontoMembraneTab);
            app.MembraneGroundTruthPreviewLabel.Text = 'Membrane Ground Truth Preview';
            app.NtoMConvertButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.NtoMConvertButton.ButtonPushedFcn = createCallbackFcn(app, @NtoMConvertButtonPushed, true);
            app.NtoMConvertButton.Enable = 'off';
            app.NtoMConvertButton.Text = 'Convert';
            app.NtoMResetButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.NtoMResetButton.ButtonPushedFcn = createCallbackFcn(app, @NtoMResetButtonPushed, true);
            app.NtoMResetButton.Text = 'Reset';
            app.FillExtracellularSpaceECSSwitchLabel = uilabel(app.NeurontoMembraneTab);
            app.FillExtracellularSpaceECSSwitchLabel.Text = 'Fill Extracellular-Space (ECS)';
            app.FillExtracellularSpaceECSSwitch = uiswitch(app.NeurontoMembraneTab, 'slider');
            app.MembranetoSkeletonTab = uitab(app.TabGroup);
            app.MembranetoSkeletonTab.Title = 'Membrane to Skeleton';
            app.SkeletonPreviewAxes = uiaxes(app.MembranetoSkeletonTab);
            title(app.SkeletonPreviewAxes, 'Title')
            xlabel(app.SkeletonPreviewAxes, 'X')
            ylabel(app.SkeletonPreviewAxes, 'Y')
            app.SkeletonPreviewAxes.Visible = 'off';
            app.ConvertMembraneGroundTruthDatatoSkeletonGroundTruthDataLabel = uilabel(app.MembranetoSkeletonTab);
            app.ConvertMembraneGroundTruthDatatoSkeletonGroundTruthDataLabel.Text = 'Convert Membrane Ground Truth Data to Skeleton Ground Truth Data';
            app.MtoSDescriptionLabel = uilabel(app.MembranetoSkeletonTab);
            app.MtoSDescriptionLabel.Text = 'Please select the ground truth directory to convert the membrane data to skeleton data';
            app.MembraneGroundTruthLabel_2 = uilabel(app.MembranetoSkeletonTab);
            app.MembraneGroundTruthLabel_2.Text = 'Membrane Ground Truth';
            app.SelectMembraneInputDirectoryButton = uibutton(app.MembranetoSkeletonTab, 'push');
            app.SelectMembraneInputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMembraneInputDirectoryButtonPushed, true);
            app.SelectMembraneInputDirectoryButton.Text = 'Select Input Directory...';
            app.SkeletonGroundTruthLabel = uilabel(app.MembranetoSkeletonTab);
            app.SkeletonGroundTruthLabel.Text = 'Skeleton Ground Truth';
            app.SelectSkeletonOutputDirectoryButton = uibutton(app.MembranetoSkeletonTab, 'push');
            app.SelectSkeletonOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSkeletonOutputDirectoryButtonPushed, true);
            app.SelectSkeletonOutputDirectoryButton.Text = 'Select Output Directory...';
            app.SkeletonGroundTruthPreviewLabel = uilabel(app.MembranetoSkeletonTab);
            app.SkeletonGroundTruthPreviewLabel.Text = 'Skeleton Ground Truth Preview';
            app.MtoSConvertButton = uibutton(app.MembranetoSkeletonTab, 'push');
            app.MtoSConvertButton.ButtonPushedFcn = createCallbackFcn(app, @MtoSConvertButtonPushed, true);
            app.MtoSConvertButton.Enable = 'off';
            app.MtoSConvertButton.Text = 'Convert';
            app.MtoSResetButton = uibutton(app.MembranetoSkeletonTab, 'push');
            app.MtoSResetButton.ButtonPushedFcn = createCallbackFcn(app, @MtoSResetButtonPushed, true);
            app.MtoSResetButton.Text = 'Reset';
            app.MembranePropertiesLabel_2 = uilabel(app.MembranetoSkeletonTab);
            app.MembranePropertiesLabel_2.Text = 'Membrane Properties';
            app.Doesthegroundtruthcontainanunknown0labelButtonGroup = uibuttongroup(app.MembranetoSkeletonTab);
            app.Doesthegroundtruthcontainanunknown0labelButtonGroup.Title = 'Does the ground truth contain an unknown "0" label?';
            app.yesButton = uitogglebutton(app.Doesthegroundtruthcontainanunknown0labelButtonGroup);
            app.yesButton.Text = 'Yes';
            app.yesButton.Value = true;
            app.noButton = uitogglebutton(app.Doesthegroundtruthcontainanunknown0labelButtonGroup);
            app.noButton.Text = 'No';
            app.GaussianFilterSizeButtonGroup = uibuttongroup(app.MembranetoSkeletonTab);
            app.GaussianFilterSizeButtonGroup.Title = 'Gaussian Filter Size';
            app.sizeOneButton = uitogglebutton(app.GaussianFilterSizeButtonGroup);
            app.sizeOneButton.Text = '1 (No Filter)';
            app.sizeThreeButton = uitogglebutton(app.GaussianFilterSizeButtonGroup);
            app.sizeThreeButton.Text = '3';
            app.sizeThreeButton.Value = true;
            app.sizeFiveButton = uitogglebutton(app.GaussianFilterSizeButtonGroup);
            app.sizeFiveButton.Text = '5';
            app.Label = uilabel(app.GaussianFilterSizeButtonGroup);
            app.Label.Text = {'A Gaussian filter is applied prior to skeletonization '; 'in order to smooth the border and prevent the '; 'generation of skeletons that over-represent cusps '; 'in irregular borders'};
            app.PatchGenerationTab = uitab(app.TabGroup);
            app.PatchGenerationTab.Title = 'Patch Generation';
            app.GeneratePatchesofInputandLabeledGroundTruthData = uilabel(app.PatchGenerationTab);
            app.GeneratePatchesofInputandLabeledGroundTruthData.Text = 'Generate Patches of Input and Labeled Ground Truth Data';
            app.MtoSDescriptionLabel_2 = uilabel(app.PatchGenerationTab);
            app.MtoSDescriptionLabel_2.Text = {'Please select the input and labeled ground truth directories to generate patches of the data for training a deep learning model. The patch generation algorithm will '; 'avoid generating patches with a high proportion of unknown pixels with a value of 0.'};
            app.InputDataLabel = uilabel(app.PatchGenerationTab);
            app.InputDataLabel.Text = 'Input Data';
            app.selectInputDirectory = uibutton(app.PatchGenerationTab, 'push');
            app.selectInputDirectory.ButtonPushedFcn = createCallbackFcn(app, @selectInputDirectoryButtonPushed, true);
            app.selectInputDirectory.Text = 'Select Input Directory...';
            app.LabeledOutputData = uilabel(app.PatchGenerationTab);
            app.LabeledOutputData.Text = 'Labeled Output Data';
            app.selectInputPatchDirectory = uibutton(app.PatchGenerationTab, 'push');
            app.selectInputPatchDirectory.ButtonPushedFcn = createCallbackFcn(app, @selectInputPatchDirectoryButtonPushed, true);
            app.selectInputPatchDirectory.Text = 'Select Output (Patch) Directory...';
            app.selectLabelDirectory = uibutton(app.PatchGenerationTab, 'push');
            app.selectLabelDirectory.ButtonPushedFcn = createCallbackFcn(app, @selectLabelDirectoryButtonPushed, true);
            app.selectLabelDirectory.Text = 'Select Input Directory...';
            app.selectLabelPatchDirectory = uibutton(app.PatchGenerationTab, 'push');
            app.selectLabelPatchDirectory.ButtonPushedFcn = createCallbackFcn(app, @selectLabelPatchDirectoryButtonPushed, true);
            app.selectLabelPatchDirectory.Text = 'Select Output (Patch) Directory...';
            app.PatchSizeEditFieldLabel = uilabel(app.PatchGenerationTab);
            app.PatchSizeEditFieldLabel.Text = 'Patch Size';
            app.PatchSizeEditField = uieditfield(app.PatchGenerationTab, 'numeric');
            app.PatchSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PatchSizeEditFieldValueChanged, true);
            app.pixelsbypixelsLabel = uilabel(app.PatchGenerationTab);
            app.pixelsbypixelsLabel.Text = 'pixels by          pixels';
            app.GeneratePatchesButton = uibutton(app.PatchGenerationTab, 'push');
            app.GeneratePatchesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePatchesButtonPushed, true);
            app.GeneratePatchesButton.Enable = 'off';
            app.GeneratePatchesButton.Text = 'Generate Patches';
            app.PatchesResetButton = uibutton(app.PatchGenerationTab, 'push');
            app.PatchesResetButton.ButtonPushedFcn = createCallbackFcn(app, @PatchesResetButtonPushed, true);
            app.PatchesResetButton.Text = 'Reset';
            app.NumberofpatchespermegapixelEditFieldLabel = uilabel(app.PatchGenerationTab);
            app.NumberofpatchespermegapixelEditFieldLabel.Text = 'Number of patches per megapixel';
            app.PatchDensityEditField = uieditfield(app.PatchGenerationTab, 'numeric');
            app.patchesLabel = uilabel(app.PatchGenerationTab);
            app.patchesLabel.Text = 'patches';
            app.advancedSettingsButton = uibutton(app.PatchGenerationTab, 'push');
            app.advancedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @advancedSettingsButtonPushed, true);
            app.advancedSettingsButton.Text = 'Advanced settings';
            app.TrainaNetworkTab = uitab(app.TabGroup);
            app.TrainaNetworkTab.Title = 'Train a Network';
            app.TrainaNetworkLabel = uilabel(app.TrainaNetworkTab);
            app.TrainaNetworkLabel.Text = 'Train a Network';
            app.InputDataDirectoryLabel = uilabel(app.TrainaNetworkTab);
            app.InputDataDirectoryLabel.Text = 'Input Data Directory';
            app.selectInputDataDirectoryButton = uibutton(app.TrainaNetworkTab, 'push');
            app.selectInputDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectInputDataDirectoryButtonPushed, true);
            app.selectInputDataDirectoryButton.Text = 'Select Input Directory...';
            app.LabelDataDirectory = uilabel(app.TrainaNetworkTab);
            app.LabelDataDirectory.Text = 'Label Data Directory';
            app.selectLabelDataDirectoryButton = uibutton(app.TrainaNetworkTab, 'push');
            app.selectLabelDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectLabelDataDirectoryButtonPushed, true);
            app.selectLabelDataDirectoryButton.Text = 'Select Label Directory...';
            app.ofepochsSpinnerLabel = uilabel(app.TrainaNetworkTab);
            app.ofepochsSpinnerLabel.Text = '# of epochs';
            app.numOfEpochsSpinner = uispinner(app.TrainaNetworkTab);
            app.numOfEpochsSpinner.Limits = [1 Inf];
            app.numOfEpochsSpinner.Value = 10;
            app.WhichnetworkwouldyouliketotrainLabel = uilabel(app.TrainaNetworkTab);
            app.WhichnetworkwouldyouliketotrainLabel.Text = 'Which network would you like to train?';
            app.networkButtonGroup = uibuttongroup(app.TrainaNetworkTab);
            app.networkButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @networkButtonGroupSelectionChanged, true);
            app.networkButtonGroup.Title = 'Select a network...';
            app.unetButton = uitogglebutton(app.networkButtonGroup);
            app.unetButton.Text = 'Untrained UNET Neural Network';
            app.unetButton.Value = true;
            app.importNetworkButton = uitogglebutton(app.networkButtonGroup);
            app.importNetworkButton.Text = 'Import Neural Network';
            app.ofclassesSpinnerLabel = uilabel(app.TrainaNetworkTab);
            app.ofclassesSpinnerLabel.Text = '# of classes';
            app.numOfClassesSpinner = uispinner(app.TrainaNetworkTab);
            app.numOfClassesSpinner.Limits = [1 Inf];
            app.numOfClassesSpinner.Value = 2;
            app.NetworkNameEditFieldLabel = uilabel(app.TrainaNetworkTab);
            app.NetworkNameEditFieldLabel.Text = 'Network Name';
            app.NetworkNameEditField = uieditfield(app.TrainaNetworkTab, 'text');
            app.SavetrainedneuralnetworktoLabel = uilabel(app.TrainaNetworkTab);
            app.SavetrainedneuralnetworktoLabel.Text = 'Save trained neural network to...';
            app.selectTrainedNetworkDirectoryButton = uibutton(app.TrainaNetworkTab, 'push');
            app.selectTrainedNetworkDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectTrainedNetworkDirectoryButtonPushed, true);
            app.selectTrainedNetworkDirectoryButton.Text = 'Select Trained Network Directory...';
            app.SavenetworkasDropDownLabel = uilabel(app.TrainaNetworkTab);
            app.SavenetworkasDropDownLabel.Text = 'Save network as...';
            app.SavenetworkasDropDown = uidropdown(app.TrainaNetworkTab);
            app.SavenetworkasDropDown.Items = {'MATLAB data (.mat)', 'Open Neural Network Exchange (.onnx)'};
            app.SavenetworkasDropDown.ItemsData = {'mat', 'onnx'};
            app.SavenetworkasDropDown.Value = 'mat';
            app.TrainNetworkButton = uibutton(app.TrainaNetworkTab, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Enable = 'off';
            app.TrainNetworkButton.Text = 'Train Network';
            app.ResetButton = uibutton(app.TrainaNetworkTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AdvancedSettingsPanel = uipanel(app.TrainaNetworkTab);
            app.AdvancedSettingsPanel.Title = 'Advanced Settings';
            app.InitialLearningRateEditFieldLabel = uilabel(app.AdvancedSettingsPanel);
            app.InitialLearningRateEditFieldLabel.Text = 'Initial Learning Rate';
            app.InitialLearningRateEditField = uieditfield(app.AdvancedSettingsPanel, 'numeric');
            app.InitialLearningRateEditField.Limits = [0 Inf];
            app.InitialLearningRateEditField.Value = 5e-05;
            app.UNETnetworkdepthEditFieldLabel = uilabel(app.AdvancedSettingsPanel);
            app.UNETnetworkdepthEditFieldLabel.Text = 'UNET network depth';
            app.UNETnetworkdepthEditField = uieditfield(app.AdvancedSettingsPanel, 'numeric');
            app.UNETnetworkdepthEditField.Limits = [1 Inf];
            app.UNETnetworkdepthEditField.Value = 5;
            app.TrainingoptimizerDropDownLabel = uilabel(app.AdvancedSettingsPanel);
            app.TrainingoptimizerDropDownLabel.Text = 'Training optimizer';
            app.TrainingoptimizerDropDown = uidropdown(app.AdvancedSettingsPanel);
            app.TrainingoptimizerDropDown.Items = {'Adam', 'Stochastic gradient descent with momentum (SGDM)', 'RMSProp'};
            app.TrainingoptimizerDropDown.ItemsData = {'adam', 'sgdm', 'rmsprop'};
            app.TrainingoptimizerDropDown.Value = 'adam';
            app.ShuffletrainingdataSwitchLabel = uilabel(app.AdvancedSettingsPanel);
            app.ShuffletrainingdataSwitchLabel.Text = 'Shuffle training data';
            app.ShuffletrainingdataSwitch = uiswitch(app.AdvancedSettingsPanel, 'slider');
            app.ShuffletrainingdataSwitch.ItemsData = {'never', 'every-epoch'};
            app.ShuffletrainingdataSwitch.Value = 'every-epoch';
            app.PrinttrainingprogressSwitchLabel = uilabel(app.AdvancedSettingsPanel);
            app.PrinttrainingprogressSwitchLabel.Text = 'Print training progress';
            app.PrinttrainingprogressSwitch = uiswitch(app.AdvancedSettingsPanel, 'slider');
            app.PrinttrainingprogressSwitch.ItemsData = {'0', '1'};
            app.PrinttrainingprogressSwitch.Value = '1';
            app.PlottrainingprogressSwitchLabel = uilabel(app.AdvancedSettingsPanel);
            app.PlottrainingprogressSwitchLabel.Text = 'Plot training progress';
            app.PlottrainingprogressSwitch = uiswitch(app.AdvancedSettingsPanel, 'slider');
            app.PlottrainingprogressSwitch.ItemsData = {'none', 'training-progress'};
            app.PlottrainingprogressSwitch.Value = 'training-progress';
            app.DroplearningrateduringtrainingLabel = uilabel(app.AdvancedSettingsPanel);
            app.DroplearningrateduringtrainingLabel.Text = {'Drop learning rate during '; 'training?'};
            app.DroplearningrateduringtrainingSwitch = uiswitch(app.AdvancedSettingsPanel, 'slider');
            app.DroplearningrateduringtrainingSwitch.ItemsData = {'none', 'piecewise'};
            app.DroplearningrateduringtrainingSwitch.ValueChangedFcn = createCallbackFcn(app, @DroplearningrateduringtrainingSwitchValueChanged, true);
            app.DroplearningrateduringtrainingSwitch.Value = 'none';
            app.ByhowmuchEditFieldLabel = uilabel(app.AdvancedSettingsPanel);
            app.ByhowmuchEditFieldLabel.Enable = 'off';
            app.ByhowmuchEditFieldLabel.Text = 'By how much?';
            app.ByhowmuchEditField = uieditfield(app.AdvancedSettingsPanel, 'numeric');
            app.ByhowmuchEditField.Limits = [0 1];
            app.ByhowmuchEditField.Enable = 'off';
            app.ByhowmuchEditField.Value = 0.1;
            app.NeuralNetworkPropertiesLabel = uilabel(app.AdvancedSettingsPanel);
            app.NeuralNetworkPropertiesLabel.Text = 'Neural Network Properties';
            app.TrainingPropertiesLabel = uilabel(app.AdvancedSettingsPanel);
            app.TrainingPropertiesLabel.Text = 'Training Properties';
            app.TrainingInformationDisplayPropertiesLabel = uilabel(app.AdvancedSettingsPanel);
            app.TrainingInformationDisplayPropertiesLabel.Text = 'Training Information Display Properties';
            app.TrainingValidationSplitTrainingDataLabel = uilabel(app.AdvancedSettingsPanel);
            app.TrainingValidationSplitTrainingDataLabel.Text = {'Training-Validation Split'; '(% Training Data)'; ''};
            app.TrainingValidationSplitTrainingDataSlider = uislider(app.AdvancedSettingsPanel);
            app.TrainingValidationSplitTrainingDataSlider.ValueChangingFcn = createCallbackFcn(app, @TrainingValidationSplitTrainingDataSliderValueChanging, true);
            app.TrainingValidationSplitTrainingDataSlider.Value = 70;
            app.trainingSplitEditField = uieditfield(app.AdvancedSettingsPanel, 'numeric');
            app.trainingSplitEditField.ValueChangedFcn = createCallbackFcn(app, @trainingSplitEditFieldValueChanged, true);
            app.trainingSplitEditField.Value = 70;
            app.EvaluateaNetworkTab = uitab(app.TabGroup);
            app.EvaluateaNetworkTab.Title = 'Evaluate a Network';
            app.PredictionPreviewAxes = uiaxes(app.EvaluateaNetworkTab);
            title(app.PredictionPreviewAxes, 'Title')
            xlabel(app.PredictionPreviewAxes, 'X')
            ylabel(app.PredictionPreviewAxes, 'Y')
            app.PredictionPreviewAxes.Visible = 'off';
            app.EvaluateaNetworkLabel = uilabel(app.EvaluateaNetworkTab);
            app.EvaluateaNetworkLabel.Text = 'Evaluate a Network';
            app.WhichnetworkwouldyouliketousetomakeapredictionLabel = uilabel(app.EvaluateaNetworkTab);
            app.WhichnetworkwouldyouliketousetomakeapredictionLabel.Text = 'Which network would you like to use to make a prediction?';
            app.selectPredictionNetworkButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.selectPredictionNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @selectPredictionNetworkButtonPushed, true);
            app.selectPredictionNetworkButton.Text = 'Select Trained Network...';
            app.SelecttheinputimagesforpredictionLabel = uilabel(app.EvaluateaNetworkTab);
            app.SelecttheinputimagesforpredictionLabel.Text = 'Select the input image(s) for prediction';
            app.selectPredictionInputButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.selectPredictionInputButton.ButtonPushedFcn = createCallbackFcn(app, @selectPredictionInputButtonPushed, true);
            app.selectPredictionInputButton.Text = 'Select input image(s)';
            app.PredictButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.ResetPredictButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.ResetPredictButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPredictButtonPushed, true);
            app.ResetPredictButton.Text = 'Reset';
            app.PreviousPredictButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.PreviousPredictButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousPredictButtonPushed, true);
            app.PreviousPredictButton.Enable = 'off';
            app.PreviousPredictButton.Text = 'Previous';
            app.NextPredictButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.NextPredictButton.ButtonPushedFcn = createCallbackFcn(app, @NextPredictButtonPushed, true);
            app.NextPredictButton.Enable = 'off';
            app.NextPredictButton.Text = 'Next';
            app.DisplayOptionsPanel = uipanel(app.EvaluateaNetworkTab);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowInputDataSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.ShowInputDataSwitchLabel.Text = 'Show Input Data';
            app.ShowInputDataSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowInputDataSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowInputDataSwitchValueChanged, true);
            app.ShowInputDataSwitch.Value = 'On';
            app.predictDisplayButtonGroup = uibuttongroup(app.DisplayOptionsPanel);
            app.predictDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @predictDisplayButtonGroupSelectionChanged, true);
            app.predictDisplayButtonGroup.Title = 'Show...';
            app.showPredictionsButton = uitogglebutton(app.predictDisplayButtonGroup);
            app.showPredictionsButton.Text = 'Predictions';
            app.showPredictionsButton.Value = true;
            app.showProbabilityButton = uitogglebutton(app.predictDisplayButtonGroup);
            app.showProbabilityButton.Text = 'Probability Map';
            app.savePredictionsToButton = uibutton(app.EvaluateaNetworkTab, 'push');
            app.savePredictionsToButton.ButtonPushedFcn = createCallbackFcn(app, @savePredictionsToButtonPushed, true);
            app.savePredictionsToButton.Enable = 'off';
            app.savePredictionsToButton.Text = 'Save these predictions to....';
            app.currentPreviewPathLabel = uilabel(app.EvaluateaNetworkTab);
            app.currentPreviewPathLabel.Text = '';
            app.PredictfromVASTTab = uitab(app.TabGroup);
            app.PredictfromVASTTab.Title = 'Predict from VAST';
            app.GeneratePredictionsfromVASTLabel = uilabel(app.PredictfromVASTTab);
            app.GeneratePredictionsfromVASTLabel.Text = 'Generate Predictions from VAST';
            app.ConnectToVASTButton = uibutton(app.PredictfromVASTTab, 'push');
            app.ConnectToVASTButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToVASTButtonPushed, true);
            app.ConnectToVASTButton.Text = 'Connect To VAST';
            app.PortEditFieldLabel = uilabel(app.PredictfromVASTTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.PredictfromVASTTab, 'numeric');
            app.PortEditField.Limits = [0 Inf];
            app.PortEditField.ValueDisplayFormat = '%d';
            app.PortEditField.Value = 22081;
            app.GeneratePredictionsfromEMVSVIPanel = uipanel(app.PredictfromVASTTab);
            app.GeneratePredictionsfromEMVSVIPanel.Title = 'Generate Predictions from EM VSVI';
            app.ImageLayerLabel_2 = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.ImageLayerLabel_2.Text = 'Image Layer';
            app.ImageLayerDropDown = uidropdown(app.GeneratePredictionsfromEMVSVIPanel);
            app.ImageLayerDropDown.Items = {'Select a VAST Layer'};
            app.ImageLayerDropDown.Value = 'Select a VAST Layer';
            app.PredictionRangeButtonGroup = uibuttongroup(app.GeneratePredictionsfromEMVSVIPanel);
            app.PredictionRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PredictionRangeButtonGroupSelectionChanged, true);
            app.PredictionRangeButtonGroup.Title = 'Prediction Range';
            app.WholeVolumeButton = uitogglebutton(app.PredictionRangeButtonGroup);
            app.WholeVolumeButton.Text = 'Whole Volume';
            app.WholeVolumeButton.Value = true;
            app.PartofVolumeButton = uitogglebutton(app.PredictionRangeButtonGroup);
            app.PartofVolumeButton.Text = 'Part of Volume';
            app.SkeletonButton = uitogglebutton(app.PredictionRangeButtonGroup);
            app.SkeletonButton.Text = 'Skeleton';
            app.MIPLevelLabel_2 = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.MIPLevelLabel_2.Text = 'MIP Level';
            app.MIPLevelSpinner = uispinner(app.GeneratePredictionsfromEMVSVIPanel);
            app.MIPLevelSpinner.Limits = [0 Inf];
            app.MIPLevelSpinner.ValueDisplayFormat = '%d';
            app.TrainedNetworkPredictionSelectButton = uibutton(app.GeneratePredictionsfromEMVSVIPanel, 'push');
            app.TrainedNetworkPredictionSelectButton.ButtonPushedFcn = createCallbackFcn(app, @TrainedNetworkPredictionSelectButtonPushed, true);
            app.TrainedNetworkPredictionSelectButton.Text = 'Select Trained Network...';
            app.ofclassesLabel = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.ofclassesLabel.Text = '# of classes';
            app.NumPredictionClassesSpinner = uispinner(app.GeneratePredictionsfromEMVSVIPanel);
            app.NumPredictionClassesSpinner.Limits = [0 Inf];
            app.NumPredictionClassesSpinner.ValueDisplayFormat = '%d';
            app.SelectProbDirectoryButton = uibutton(app.GeneratePredictionsfromEMVSVIPanel, 'push');
            app.SelectProbDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectProbDirectoryButtonPushed, true);
            app.SelectProbDirectoryButton.Text = 'Select probabilities directory...';
            app.SelectPredDirectoryButton = uibutton(app.GeneratePredictionsfromEMVSVIPanel, 'push');
            app.SelectPredDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPredDirectoryButtonPushed, true);
            app.SelectPredDirectoryButton.Text = 'Select predictions directory...';
            app.PredictFromVSVIButton = uibutton(app.GeneratePredictionsfromEMVSVIPanel, 'push');
            app.PredictFromVSVIButton.ButtonPushedFcn = createCallbackFcn(app, @PredictFromVSVIButtonPushed, true);
            app.PredictFromVSVIButton.Text = 'Predict!';
            app.ZLabel = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.ZLabel.Enable = 'off';
            app.ZLabel.Text = 'Z';
            app.ZStartEditField = uieditfield(app.GeneratePredictionsfromEMVSVIPanel, 'numeric');
            app.ZStartEditField.ValueDisplayFormat = '%11.4f';
            app.ZStartEditField.Enable = 'off';
            app.toLabel_3 = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.toLabel_3.Enable = 'off';
            app.toLabel_3.Text = 'to';
            app.ZEndEditField = uieditfield(app.GeneratePredictionsfromEMVSVIPanel, 'numeric');
            app.ZEndEditField.ValueDisplayFormat = '%11.4f';
            app.ZEndEditField.Enable = 'off';
            app.ZFullrangeCheckBox = uicheckbox(app.GeneratePredictionsfromEMVSVIPanel);
            app.ZFullrangeCheckBox.Enable = 'off';
            app.ZFullrangeCheckBox.Text = 'Full range';
            app.YLabel = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.YLabel.Enable = 'off';
            app.YLabel.Text = 'Y';
            app.YStartEditField = uieditfield(app.GeneratePredictionsfromEMVSVIPanel, 'numeric');
            app.YStartEditField.ValueDisplayFormat = '%11.4f';
            app.YStartEditField.Enable = 'off';
            app.toLabel_2 = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.toLabel_2.Enable = 'off';
            app.toLabel_2.Text = 'to';
            app.YEndEditField = uieditfield(app.GeneratePredictionsfromEMVSVIPanel, 'numeric');
            app.YEndEditField.ValueDisplayFormat = '%11.4f';
            app.YEndEditField.Enable = 'off';
            app.YFullrangeCheckBox = uicheckbox(app.GeneratePredictionsfromEMVSVIPanel);
            app.YFullrangeCheckBox.Enable = 'off';
            app.YFullrangeCheckBox.Text = 'Full range';
            app.XLabel = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.XLabel.Enable = 'off';
            app.XLabel.Text = 'X';
            app.XStartEditField = uieditfield(app.GeneratePredictionsfromEMVSVIPanel, 'numeric');
            app.XStartEditField.ValueDisplayFormat = '%11.4f';
            app.XStartEditField.Enable = 'off';
            app.toLabel = uilabel(app.GeneratePredictionsfromEMVSVIPanel);
            app.toLabel.Enable = 'off';
            app.toLabel.Text = 'to';
            app.XEndEditField = uieditfield(app.GeneratePredictionsfromEMVSVIPanel, 'numeric');
            app.XEndEditField.ValueDisplayFormat = '%11.4f';
            app.XEndEditField.Enable = 'off';
            app.XFullrangeCheckBox = uicheckbox(app.GeneratePredictionsfromEMVSVIPanel);
            app.XFullrangeCheckBox.Enable = 'off';
            app.XFullrangeCheckBox.Text = 'Full range';
            app.GenerateVSVICheckBox = uicheckbox(app.GeneratePredictionsfromEMVSVIPanel);
            app.GenerateVSVICheckBox.Text = 'Generate VSVI';
            app.ConvertVSVItoVSSEGPanel = uipanel(app.PredictfromVASTTab);
            app.ConvertVSVItoVSSEGPanel.Title = 'Convert VSVI to VSSEG';
            app.ImageLayerLabel = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.ImageLayerLabel.Text = 'Image Layer';
            app.SegmentationLayerLabel = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.SegmentationLayerLabel.Text = 'Segmentation Layer';
            app.AnnotationLayerLabel_2 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.AnnotationLayerLabel_2.Text = 'Annotation Layer';
            app.SourceAnnotationLayerLabel = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.SourceAnnotationLayerLabel.Text = 'Source Annotation Layer';
            app.ConversionImageLayerDropDown = uidropdown(app.ConvertVSVItoVSSEGPanel);
            app.ConversionImageLayerDropDown.Items = {'Select a VAST Layer'};
            app.ConversionImageLayerDropDown.Value = 'Select a VAST Layer';
            app.ConversionSegLayerDropDown = uidropdown(app.ConvertVSVItoVSSEGPanel);
            app.ConversionSegLayerDropDown.Items = {'Select a VAST Layer'};
            app.ConversionSegLayerDropDown.Value = 'Select a VAST Layer';
            app.ConversionAnnoLayerDropDown = uidropdown(app.ConvertVSVItoVSSEGPanel);
            app.ConversionAnnoLayerDropDown.Items = {'Select a VAST Layer'};
            app.ConversionAnnoLayerDropDown.Value = 'Select a VAST Layer';
            app.ConversionSourceAnnoLayerDropDown = uidropdown(app.ConvertVSVItoVSSEGPanel);
            app.ConversionSourceAnnoLayerDropDown.Items = {'Select a VAST Layer'};
            app.ConversionSourceAnnoLayerDropDown.Value = 'Select a VAST Layer';
            app.ConversionMIPLevelLabel = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.ConversionMIPLevelLabel.Text = 'MIP Level';
            app.ConversionMIPLevelSpinner = uispinner(app.ConvertVSVItoVSSEGPanel);
            app.ConversionMIPLevelSpinner.Limits = [0 Inf];
            app.ConversionMIPLevelSpinner.RoundFractionalValues = 'on';
            app.ConversionMIPLevelSpinner.ValueDisplayFormat = '%d';
            app.TileSizeLabel = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.TileSizeLabel.Text = 'Tile Size';
            app.ConversionTileSizeEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionTileSizeEditField.RoundFractionalValues = 'on';
            app.ConversionTileSizeEditField.ValueDisplayFormat = '%d';
            app.ThresholdLabel = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.ConversionThresholdEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionThresholdEditField.Limits = [0 Inf];
            app.ConversionThresholdEditField.ValueDisplayFormat = '%11.1f';
            app.ConvertButton = uibutton(app.ConvertVSVItoVSSEGPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert!';
            app.VSSEGLocationMethodButtonGroup = uibuttongroup(app.ConvertVSVItoVSSEGPanel);
            app.VSSEGLocationMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VSSEGLocationMethodButtonGroupSelectionChanged, true);
            app.VSSEGLocationMethodButtonGroup.Title = 'VSSEG Location Method';
            app.SourceSkeletonButton_2 = uitogglebutton(app.VSSEGLocationMethodButtonGroup);
            app.SourceSkeletonButton_2.Text = 'Source Skeleton';
            app.SourceSkeletonButton_2.Value = true;
            app.RandomButton = uitogglebutton(app.VSSEGLocationMethodButtonGroup);
            app.RandomButton.Text = 'Random';
            app.ZLabel_2 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.ZLabel_2.Visible = 'off';
            app.ZLabel_2.Text = 'Z';
            app.ConversionZStartEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionZStartEditField.ValueDisplayFormat = '%11.4f';
            app.ConversionZStartEditField.Visible = 'off';
            app.toLabel_4 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.toLabel_4.Visible = 'off';
            app.toLabel_4.Text = 'to';
            app.ConversionZEndEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionZEndEditField.ValueDisplayFormat = '%11.4f';
            app.ConversionZEndEditField.Visible = 'off';
            app.ConversionZFullRangeCheckbox = uicheckbox(app.ConvertVSVItoVSSEGPanel);
            app.ConversionZFullRangeCheckbox.Visible = 'off';
            app.ConversionZFullRangeCheckbox.Text = 'Full range';
            app.YLabel_2 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.YLabel_2.Visible = 'off';
            app.YLabel_2.Text = 'Y';
            app.ConversionYStartEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionYStartEditField.ValueDisplayFormat = '%11.4f';
            app.ConversionYStartEditField.Visible = 'off';
            app.toLabel_5 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.toLabel_5.Visible = 'off';
            app.toLabel_5.Text = 'to';
            app.EditField_12 = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%11.4f';
            app.EditField_12.Visible = 'off';
            app.ConversionYFullRangeCheckbox = uicheckbox(app.ConvertVSVItoVSSEGPanel);
            app.ConversionYFullRangeCheckbox.Visible = 'off';
            app.ConversionYFullRangeCheckbox.Text = 'Full range';
            app.XLabel_2 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.XLabel_2.Visible = 'off';
            app.XLabel_2.Text = 'X';
            app.ConversionXStartEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionXStartEditField.ValueDisplayFormat = '%11.4f';
            app.ConversionXStartEditField.Visible = 'off';
            app.toLabel_6 = uilabel(app.ConvertVSVItoVSSEGPanel);
            app.toLabel_6.Visible = 'off';
            app.toLabel_6.Text = 'to';
            app.ConversionXEndEditField = uieditfield(app.ConvertVSVItoVSSEGPanel, 'numeric');
            app.ConversionXEndEditField.ValueDisplayFormat = '%11.4f';
            app.ConversionXEndEditField.Visible = 'off';
            app.ConversionXFullRangeCheckbox = uicheckbox(app.ConvertVSVItoVSSEGPanel);
            app.ConversionXFullRangeCheckbox.Visible = 'off';
            app.ConversionXFullRangeCheckbox.Text = 'Full range';
            % Show the figure after all components are created
            app.mEMbrainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mEMbrain_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mEMbrainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mEMbrainUIFigure)
        end
    end
end